classdef DataVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Toolbar                        matlab.ui.container.Toolbar
        DebugToggleTool                matlab.ui.container.toolbar.ToggleTool
        HelpPushTool                   matlab.ui.container.toolbar.PushTool
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        AnalyzeTab                     matlab.ui.container.Tab
        AnalyzeLayout                  matlab.ui.container.GridLayout
        MetaDataPanel                  matlab.ui.container.Panel
        MetaDataLayout                 matlab.ui.container.GridLayout
        SecondaryEditField             matlab.ui.control.EditField
        SecondaryEditFieldLabel        matlab.ui.control.Label
        PrimaryEditField               matlab.ui.control.EditField
        PrimaryEditFieldLabel          matlab.ui.control.Label
        InstrumentEditField            matlab.ui.control.EditField
        InstrumentEditFieldLabel       matlab.ui.control.Label
        AnalyzeButtonsLayout           matlab.ui.container.GridLayout
        VersionLabel                   matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ProcessDataButton              matlab.ui.control.Button
        SettingsPanel                  matlab.ui.container.Panel
        AnalyzeSettingsLayout          matlab.ui.container.GridLayout
        MetaDataPatternEditField       matlab.ui.control.EditField
        MetaDataPatternEditFieldLabel  matlab.ui.control.Label
        EventPatternEditField          matlab.ui.control.EditField
        EventPatternEditFieldLabel     matlab.ui.control.Label
        HKPatternEditField             matlab.ui.control.EditField
        HKPatternEditFieldLabel        matlab.ui.control.Label
        SciencePatternEditField        matlab.ui.control.EditField
        SciencePatternEditFieldLabel   matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
        LocationEditField              matlab.ui.control.EditField
        LocationEditFieldLabel         matlab.ui.control.Label
        ExportTab                      matlab.ui.container.Tab
        ExportLayout                   matlab.ui.container.GridLayout
        ExportSettingsPanel            matlab.ui.container.Panel
        ExportSettingsLayout           matlab.ui.container.GridLayout
        EndTimeEditField               matlab.ui.control.EditField
        EndDateTimeDatePicker          matlab.ui.control.DatePicker
        EndDateTimeDatePickerLabel     matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.EditField
        StartDateTimeDatePicker        matlab.ui.control.DatePicker
        StartDateTimeDatePickerLabel   matlab.ui.control.Label
        ExportButtonsLayout            matlab.ui.container.GridLayout
        ExportNoteLabel                matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        ExportFormatDropDown           matlab.ui.control.DropDown
        ExportFormatDropDownLabel      matlab.ui.control.Label
        VisualizeTab                   matlab.ui.container.Tab
        VisualizeLayout                matlab.ui.container.GridLayout
        VisualizeButtonsLayout         matlab.ui.container.GridLayout
        SaveFiguresButton              matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        VisualizeSettingsPanel         matlab.ui.container.Panel
        VisualizeSettingsLayout        matlab.ui.container.GridLayout
        TypeButtonGroup                matlab.ui.container.ButtonGroup
        CustomButton                   matlab.ui.control.ToggleButton
        SFTButton                      matlab.ui.control.ToggleButton
        CPTButton                      matlab.ui.control.ToggleButton
        ATButton                       matlab.ui.control.ToggleButton
        PSDStartTimeEditField          matlab.ui.control.EditField
        PSDStartDateTimeDatePicker     matlab.ui.control.DatePicker
        PSDStartDateTimeDatePickerLabel  matlab.ui.control.Label
        PlotSelectionTree              matlab.ui.container.CheckBoxTree
        FieldNode                      matlab.ui.container.TreeNode
        TogetherNode                   matlab.ui.container.TreeNode
        SeparateNode                   matlab.ui.container.TreeNode
        PSDNode                        matlab.ui.container.TreeNode
        HKNode                         matlab.ui.container.TreeNode
        ModeCyclingNode                matlab.ui.container.TreeNode
        ModeCyclingFieldNode           matlab.ui.container.TreeNode
        ModeCyclingPSD                 matlab.ui.container.TreeNode
        FinalNormalModeNode            matlab.ui.container.TreeNode
        RangeCyclingNode               matlab.ui.container.TreeNode
        RangeCyclingFieldNode          matlab.ui.container.TreeNode
        RangeCyclingPSDNode            matlab.ui.container.TreeNode
        RampModeNode                   matlab.ui.container.TreeNode
        PSDDurationSpinner             matlab.ui.control.Spinner
        PSDDurationhLabel              matlab.ui.control.Label
        CropSpinner                    matlab.ui.control.Spinner
        CropminutesLabel               matlab.ui.control.Label
    end

    properties (Access = private)
        PreviousError MException {mustBeScalarOrEmpty}
        DebugStatus struct {mustBeScalarOrEmpty} = dbstatus();
    end

    properties (SetObservable, Access = private)
        Analysis mag.AutomatedAnalysis {mustBeScalarOrEmpty}
        Figures (1, :) matlab.ui.Figure
    end

    properties (Dependent, Access = private)
        ResultsLocation (1, 1) string {mustBeFolder}
    end

    methods

        function value = get.ResultsLocation(app)

            value = fullfile(app.LocationEditField.Value, compose("Results (v%s)", mag.version()));

            if ~isfolder(value)
                mkdir(value);
            end
        end
    end

    methods (Access = private)

        function analysisChanged(app, ~, ~)

            resultsAvailable = ~isempty(app.Analysis) && ~isempty(app.Analysis.Results.Primary) && ~isempty(app.Analysis.Results.Secondary);

            % Enable/disable buttons.
            status = matlab.lang.OnOffSwitchState(resultsAvailable);

            [app.ExportFormatDropDown.Enable, app.ExportButton.Enable, app.PlotButton.Enable, ...
                app.ExportSettingsPanel.Enable, app.VisualizeSettingsPanel.Enable] = deal(status);

            % Set values in app.
            if resultsAvailable

                results = app.Analysis.Results;

                app.InstrumentEditField.Value = compose("%s - BSW: %s - ASW: %s", results.MetaData.Model, results.MetaData.BSW, results.MetaData.ASW);
                app.PrimaryEditField.Value = compose("%s (%s - %s - %s)", results.Primary.MetaData.getDisplay("Sensor"), results.Primary.MetaData.getDisplay("FEE"), results.Primary.MetaData.getDisplay("Model"), results.Primary.MetaData.getDisplay("Can"));
                app.SecondaryEditField.Value = compose("%s (%s - %s - %s)", results.Secondary.MetaData.getDisplay("Sensor"), results.Secondary.MetaData.getDisplay("FEE"), results.Secondary.MetaData.getDisplay("Model"), results.Secondary.MetaData.getDisplay("Can"));

                scienceStartDatetime = max(results.Primary.MetaData.Timestamp, results.Secondary.MetaData.Timestamp);

                app.PSDStartDateTimeDatePicker.Value = scienceStartDatetime;
                app.PSDStartTimeEditField.Value = datestr(scienceStartDatetime, "hh:mm:ss"); %#ok<DATST>
            else

                app.InstrumentEditField.Value = string.empty();
                app.PrimaryEditField.Value = string.empty();
                app.SecondaryEditField.Value = string.empty();

                app.PSDStartDateTimeDatePicker.Value = NaT();
                app.PSDStartTimeEditField.Value = string.empty();
            end
        end

        function figuresChanged(app, varargin)
            app.SaveFiguresButton.Enable = matlab.lang.OnOffSwitchState(~isempty(app.Figures));
        end

        function displayError(app, error, title)

            arguments
                app
                error (1, 1) {mustBeA(error, ["string", "MException"])}
                title (1, 1) string = "Something Went Wrong..."
            end

            if isa(error, "MException")

                app.PreviousError = error;
                message = error.message;
            else
                message = error;
            end

            uialert(app.UIFigure, message, title, Icon = "error", Interpreter = "html");
        end

        function closeProgressBar = overlayProgressBar(app, message)

            arguments (Input)
                app
                message (1, 1) string
            end

            arguments (Output)
                closeProgressBar (1, 2) onCleanup
            end

            progressBar = uiprogressdlg(app.UIFigure, Message = message, Icon = "info", Indeterminate = "on");
            closeProgressBar = [onCleanup(@() delete(progressBar)), onCleanup(@() beep())];
        end
    end

    methods (Static, Access = private)

        function dateTime = combineDateAndTime(date, time)

            dateTime = date;

            if ~isempty(time)
                dateTime = dateTime + duration(time, InputFormat = "hh:mm:ss");
            end

            dateTime.Format = mag.process.DateTime.Format;
            dateTime.TimeZone = "UTC";
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Set app version.
            app.VersionLabel.Text = compose("Version %s", mag.version());

            % Set default patterns.
            dummyAnalysis = mag.AutomatedAnalysis();

            app.EventPatternEditField.Value = join(dummyAnalysis.EventPattern, pathsep());
            app.MetaDataPatternEditField.Value = join(dummyAnalysis.MetaDataPattern, pathsep());
            app.SciencePatternEditField.Value = dummyAnalysis.SciencePattern;
            app.HKPatternEditField.Value = join(dummyAnalysis.HKPattern, pathsep());

            % Subscribe to properties.
            app.addlistener("Analysis", "PostSet", @app.analysisChanged);
            app.addlistener("Figures", "PostSet", @app.figuresChanged);
        end

        % Button pushed function: ProcessDataButton
        function ProcessDataButtonPushed(app, event)

            % Validate location.
            location = app.LocationEditField.Value;
            if isempty(location)

                app.displayError("Location is empty.", "Invalid Location");
                return;
            elseif ~isfolder(location)

                app.displayError(compose("Location ""%s"" does not exist.", location), "Invalid Location");
                return;
            end

            % Show progress bar.
            closeProgressBar = app.overlayProgressBar("Processing data..."); %#ok<NASGU>

            % Disable warning backtraces.
            previousWarningState = warning("off", "backtrace");
            restoreWarningState = onCleanup(@() warning(previousWarningState));

            % Start analysis.
            try

                app.Analysis = mag.AutomatedAnalysis.start(Location = app.LocationEditField.Value, ...
                    EventPattern = split(app.EventPatternEditField.Value, pathsep()), ...
                    MetaDataPattern = split(app.MetaDataPatternEditField.Value, pathsep()), ...
                    SciencePattern = app.SciencePatternEditField.Value, ...
                    HKPattern = split(app.HKPatternEditField.Value, pathsep()));
            catch exception

                app.displayError(exception);
                return;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            % Set plotting function arguments.
            args = {app.Analysis, "Filter", minutes(app.CropSpinner.Value)};

            % Show progress bar.
            closeProgressBar = app.overlayProgressBar("Plotting data..."); %#ok<NASGU>

            % Select plotting function based on plot types.
            try

                switch app.TypeButtonGroup.SelectedObject.Text
                    case {"AT", "SFT"}

                        if ismissing(app.PSDStartDateTimeDatePicker.Value)
                            psdStartTime = datetime.empty();
                        else
                            psdStartTime = app.combineDateAndTime(app.PSDStartDateTimeDatePicker.Value, app.PSDStartTimeEditField.Value);
                        end

                        args = [args, ...
                            {"PSDStart", psdStartTime, ...
                            "PSDDuration", hours(app.PSDDurationSpinner.Value)}];

                        app.Figures = mag.graphics.sftPlots(args{:});
                    case "CPT"
                        app.Figures = mag.graphics.cptPlots(args{:});
                    otherwise
                        app.displayError("Unrecognized plot type selected.", "Error");
                end
            catch exception
                app.displayError(exception);
            end
        end

        % Button pushed function: SaveFiguresButton
        function SaveFiguresButtonPushed(app, event)

            closeProgressBar = app.overlayProgressBar("Saving figures..."); %#ok<NASGU>

            try
                mag.graphics.savePlots(app.Figures, app.ResultsLocation);
            catch exception
                app.displayError(exception);
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)

            closeProgressBar = app.overlayProgressBar("Exporting..."); %#ok<NASGU>

            format = app.ExportFormatDropDown.Value;

            switch format
                case "Workspace"

                    assignin("base", "analysis", app.Analysis);
                    return;
                case "Analysis"

                    analysis = app.Analysis;
                    save(fullfile(app.ResultsLocation, "Data.mat"), "analysis");
                    return;
                case "MAT"
                    exportStrategy = mag.io.MAT();
                case "DAT"
                    exportStrategy = mag.io.DAT();
                otherwise
                    app.displayError(compose("Unrecognized export format option ""%s"".", format));
            end

            try

                startTime = app.combineDateAndTime(app.StartDateTimeDatePicker.Value, app.StartTimeEditField.Value);
                endTime = app.combineDateAndTime(app.EndDateTimeDatePicker.Value, app.EndTimeEditField.Value);

                app.Analysis.export(exportStrategy, Location = app.ResultsLocation, StartTime = startTime, EndTime = endTime);
            catch exception
                app.displayError(exception);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            location = uigetdir(app.LocationEditField.Value, "Select Data Root");

            if ~isequal(location, 0)
                app.LocationEditField.Value = location;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

            app.Analysis = mag.AutomatedAnalysis.empty();
            app.Figures = matlab.ui.Figure.empty();
        end

        % Callback function: HelpPushTool
        function HelpPushToolClicked(app, event)

            % Show progress bar.
            closeProgressBar = app.overlayProgressBar("Generating diagnostics..."); %#ok<NASGU>

            % Initialize variables to save.
            analysis = app.Analysis;

            exportStartDate = app.StartDateTimeDatePicker.Value;
            exportStartTime = app.StartTimeEditField.Value;
            exportEndDate = app.EndDateTimeDatePicker.Value;
            exportEndTime = app.EndTimeEditField.Value;

            selectedPlotType = app.TypeButtonGroup.SelectedObject.Text;
            cropTime = app.CropSpinner.Value;
            psdStartDate = app.PSDStartDateTimeDatePicker.Value;
            psdStartTime = app.PSDStartTimeEditField.Value;
            psdDuration = app.PSDDurationSpinner.Value;

            % Create folder to zip.
            statusFolder = tempname();
            zipFolder = statusFolder + ".zip";

            mkdir(statusFolder);
            deleteFolder = onCleanup(@() rmdir(statusFolder, "s"));

            % Create MAT file with variables.
            save(fullfile(statusFolder, "data.mat"), "analysis", ...
                "exportStartDate", "exportStartTime", "exportEndDate", "exportEndTime", ...
                "selectedPlotType", "cropTime", "psdStartDate", "psdStartTime", "psdDuration");
            exportapp(app.UIFigure, fullfile(statusFolder, "app.png"));

            zip(zipFolder, statusFolder);
            clipboard("copy", zipFolder);

            % Show dialog.
            uialert(app.UIFigure, compose("Share ZIP file ""%s""" + newline() + "with the developer. Path copied to clipboard.", zipFolder), "Share Diagnostics", Icon = "info");
        end

        % On callback: DebugToggleTool
        function DebugToggleToolOn(app, event)

            app.DebugStatus = dbstatus();

            if ~isempty(app.PreviousError)

                stack = app.PreviousError.stack;
                dbstop("in", stack(1).file, "at", num2str(stack(1).line));
            end
        end

        % Off callback: DebugToggleTool
        function DebugToggleToolOff(app, event)

            dbclear("all");
            dbstop(app.DebugStatus);
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)

            app.PlotSelectionTree.Enable = "off";

            switch app.TypeButtonGroup.SelectedObject.Text
                case {"AT", "SFT"}

                    app.PlotSelectionTree.CheckedNodes = [app.SeparateNode, ...
                        app.PSDNode, ...
                        app.HKNode];

                    app.PlotSelectionTree.collapse();
                    app.FieldNode.expand();
                case "CPT"

                    app.PlotSelectionTree.CheckedNodes = [app.TogetherNode, ...
                        app.HKNode, ...
                        app.ModeCyclingNode, ...
                        app.FinalNormalModeNode, ...
                        app.RangeCyclingNode, ...
                        app.RampModeNode];

                    app.PlotSelectionTree.collapse();
                    app.FieldNode.expand();
                case "Custom"

                    app.PlotSelectionTree.Enable = "on";
                    app.PlotSelectionTree.expand();
                otherwise
                    app.displayError("Unrecognized plot type selected.", "Error");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.DebugToggleTool = uitoggletool(app.Toolbar);
            app.DebugToggleTool.Icon = fullfile(pathToMLAPP, 'icons', 'debug.png');
            app.DebugToggleTool.OffCallback = createCallbackFcn(app, @DebugToggleToolOff, true);
            app.DebugToggleTool.OnCallback = createCallbackFcn(app, @DebugToggleToolOn, true);
            app.HelpPushTool = uipushtool(app.Toolbar);
            app.HelpPushTool.ClickedCallback = createCallbackFcn(app, @HelpPushToolClicked, true);
            app.HelpPushTool.Icon = fullfile(pathToMLAPP, 'icons', 'help.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.AnalyzeLayout = uigridlayout(app.AnalyzeTab);
            app.SettingsPanel = uipanel(app.AnalyzeLayout);
            app.SettingsPanel.Title = 'Settings';
            app.AnalyzeSettingsLayout = uigridlayout(app.SettingsPanel);
            app.LocationEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.LocationEditFieldLabel.Text = 'Location:';
            app.LocationEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.BrowseButton = uibutton(app.AnalyzeSettingsLayout, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SciencePatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.SciencePatternEditFieldLabel.Text = 'Science Pattern:';
            app.SciencePatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.HKPatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.HKPatternEditFieldLabel.Text = 'HK Pattern:';
            app.HKPatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.EventPatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.EventPatternEditFieldLabel.Text = 'Event Pattern:';
            app.EventPatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.MetaDataPatternEditFieldLabel = uilabel(app.AnalyzeSettingsLayout);
            app.MetaDataPatternEditFieldLabel.Text = 'Meta Data Pattern:';
            app.MetaDataPatternEditField = uieditfield(app.AnalyzeSettingsLayout, 'text');
            app.AnalyzeButtonsLayout = uigridlayout(app.AnalyzeLayout);
            app.ProcessDataButton = uibutton(app.AnalyzeButtonsLayout, 'push');
            app.ProcessDataButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessDataButtonPushed, true);
            app.ProcessDataButton.Text = 'Process Data';
            app.ResetButton = uibutton(app.AnalyzeButtonsLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.VersionLabel = uilabel(app.AnalyzeButtonsLayout);
            app.VersionLabel.Text = '';
            app.MetaDataPanel = uipanel(app.AnalyzeLayout);
            app.MetaDataPanel.Title = 'Meta Data';
            app.MetaDataLayout = uigridlayout(app.MetaDataPanel);
            app.InstrumentEditFieldLabel = uilabel(app.MetaDataLayout);
            app.InstrumentEditFieldLabel.Text = 'Instrument';
            app.InstrumentEditField = uieditfield(app.MetaDataLayout, 'text');
            app.InstrumentEditField.Editable = 'off';
            app.PrimaryEditFieldLabel = uilabel(app.MetaDataLayout);
            app.PrimaryEditFieldLabel.Text = 'Primary';
            app.PrimaryEditField = uieditfield(app.MetaDataLayout, 'text');
            app.PrimaryEditField.Editable = 'off';
            app.SecondaryEditFieldLabel = uilabel(app.MetaDataLayout);
            app.SecondaryEditFieldLabel.Text = 'Secondary';
            app.SecondaryEditField = uieditfield(app.MetaDataLayout, 'text');
            app.SecondaryEditField.Editable = 'off';
            app.ExportTab = uitab(app.TabGroup);
            app.ExportTab.Title = 'Export';
            app.ExportLayout = uigridlayout(app.ExportTab);
            app.ExportButtonsLayout = uigridlayout(app.ExportLayout);
            app.ExportFormatDropDownLabel = uilabel(app.ExportButtonsLayout);
            app.ExportFormatDropDownLabel.Text = 'Export Format:';
            app.ExportFormatDropDown = uidropdown(app.ExportButtonsLayout);
            app.ExportFormatDropDown.Items = {'Workspace', 'Analysis', 'MAT', 'DAT'};
            app.ExportFormatDropDown.Enable = 'off';
            app.ExportFormatDropDown.Value = 'Workspace';
            app.ExportButton = uibutton(app.ExportButtonsLayout, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export';
            app.ExportNoteLabel = uilabel(app.ExportButtonsLayout);
            app.ExportNoteLabel.Text = {'Note: Export start and end times do not apply'; 'to "Workspace" and "Analysis" formats.'};
            app.ExportSettingsPanel = uipanel(app.ExportLayout);
            app.ExportSettingsPanel.Enable = 'off';
            app.ExportSettingsPanel.Title = 'Settings';
            app.ExportSettingsLayout = uigridlayout(app.ExportSettingsPanel);
            app.StartDateTimeDatePickerLabel = uilabel(app.ExportSettingsLayout);
            app.StartDateTimeDatePickerLabel.Text = 'Start Date/Time:';
            app.StartDateTimeDatePicker = uidatepicker(app.ExportSettingsLayout);
            app.StartTimeEditField = uieditfield(app.ExportSettingsLayout, 'text');
            app.EndDateTimeDatePickerLabel = uilabel(app.ExportSettingsLayout);
            app.EndDateTimeDatePickerLabel.Text = 'End Date/Time:';
            app.EndDateTimeDatePicker = uidatepicker(app.ExportSettingsLayout);
            app.EndTimeEditField = uieditfield(app.ExportSettingsLayout, 'text');
            app.VisualizeTab = uitab(app.TabGroup);
            app.VisualizeTab.Title = 'Visualize';
            app.VisualizeLayout = uigridlayout(app.VisualizeTab);
            app.VisualizeSettingsPanel = uipanel(app.VisualizeLayout);
            app.VisualizeSettingsPanel.Enable = 'off';
            app.VisualizeSettingsPanel.Title = 'Settings';
            app.VisualizeSettingsLayout = uigridlayout(app.VisualizeSettingsPanel);
            app.CropminutesLabel = uilabel(app.VisualizeSettingsLayout);
            app.CropminutesLabel.Text = 'Crop [m]:';
            app.CropSpinner = uispinner(app.VisualizeSettingsLayout);
            app.CropSpinner.Value = 1;
            app.PSDDurationhLabel = uilabel(app.VisualizeSettingsLayout);
            app.PSDDurationhLabel.Text = 'PSD Duration [h]: ';
            app.PSDDurationSpinner = uispinner(app.VisualizeSettingsLayout);
            app.PSDDurationSpinner.Value = 1;
            app.PlotSelectionTree = uitree(app.VisualizeSettingsLayout, 'checkbox');
            app.PlotSelectionTree.Enable = 'off';
            app.FieldNode = uitreenode(app.PlotSelectionTree);
            app.FieldNode.Text = 'Field';
            app.TogetherNode = uitreenode(app.FieldNode);
            app.TogetherNode.Text = 'Together';
            app.SeparateNode = uitreenode(app.FieldNode);
            app.SeparateNode.Text = 'Separate';
            app.PSDNode = uitreenode(app.PlotSelectionTree);
            app.PSDNode.Text = 'PSD';
            app.HKNode = uitreenode(app.PlotSelectionTree);
            app.HKNode.Text = 'HK';
            app.ModeCyclingNode = uitreenode(app.PlotSelectionTree);
            app.ModeCyclingNode.Text = 'Mode Cycling';
            app.ModeCyclingFieldNode = uitreenode(app.ModeCyclingNode);
            app.ModeCyclingFieldNode.Text = 'Field';
            app.ModeCyclingPSD = uitreenode(app.ModeCyclingNode);
            app.ModeCyclingPSD.Text = 'PSD';
            app.FinalNormalModeNode = uitreenode(app.PlotSelectionTree);
            app.FinalNormalModeNode.Text = 'Final Normal Mode';
            app.RangeCyclingNode = uitreenode(app.PlotSelectionTree);
            app.RangeCyclingNode.Text = 'Range Cycling';
            app.RangeCyclingFieldNode = uitreenode(app.RangeCyclingNode);
            app.RangeCyclingFieldNode.Text = 'Field';
            app.RangeCyclingPSDNode = uitreenode(app.RangeCyclingNode);
            app.RangeCyclingPSDNode.Text = 'PSD';
            app.RampModeNode = uitreenode(app.PlotSelectionTree);
            app.RampModeNode.Text = 'Ramp Mode';
            % Assign Checked Nodes
            app.PlotSelectionTree.CheckedNodes = [app.PSDNode, app.HKNode, app.SeparateNode];
            app.PSDStartDateTimeDatePickerLabel = uilabel(app.VisualizeSettingsLayout);
            app.PSDStartDateTimeDatePickerLabel.Text = 'PSD Start Date/Time:';
            app.PSDStartDateTimeDatePicker = uidatepicker(app.VisualizeSettingsLayout);
            app.PSDStartTimeEditField = uieditfield(app.VisualizeSettingsLayout, 'text');
            app.TypeButtonGroup = uibuttongroup(app.VisualizeSettingsLayout);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.ATButton = uitogglebutton(app.TypeButtonGroup);
            app.ATButton.Text = 'AT';
            app.ATButton.Value = true;
            app.CPTButton = uitogglebutton(app.TypeButtonGroup);
            app.CPTButton.Text = 'CPT';
            app.SFTButton = uitogglebutton(app.TypeButtonGroup);
            app.SFTButton.Text = 'SFT';
            app.CustomButton = uitogglebutton(app.TypeButtonGroup);
            app.CustomButton.Enable = 'off';
            app.CustomButton.Text = 'Custom';
            app.VisualizeButtonsLayout = uigridlayout(app.VisualizeLayout);
            app.PlotButton = uibutton(app.VisualizeButtonsLayout, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Enable = 'off';
            app.PlotButton.Text = 'Plot';
            app.SaveFiguresButton = uibutton(app.VisualizeButtonsLayout, 'push');
            app.SaveFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFiguresButtonPushed, true);
            app.SaveFiguresButton.Enable = 'off';
            app.SaveFiguresButton.Text = 'Save Figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end